classdef main < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        SelectManualyPanel              matlab.ui.container.Panel
        ResetAllButton                  matlab.ui.control.Button
        CalculateAutoPanel              matlab.ui.container.Panel
        ResetCalAutoButton              matlab.ui.control.Button
        AxesSelectButtonGroup           matlab.ui.container.ButtonGroup
        zButton                         matlab.ui.control.RadioButton
        yButton                         matlab.ui.control.RadioButton
        xButton                         matlab.ui.control.RadioButton
        cutWidthTEditField              matlab.ui.control.NumericEditField
        cutWidthTEditFieldLabel         matlab.ui.control.Label
        CalculateCircumButton           matlab.ui.control.Button
        PostureControlPanel             matlab.ui.container.Panel
        OKButton                        matlab.ui.control.Button
        ResetPostureButton              matlab.ui.control.Button
        TrimerangeDown                  matlab.ui.control.NumericEditField
        Label_2                         matlab.ui.control.Label
        TrimerangeUp                    matlab.ui.control.NumericEditField
        Label                           matlab.ui.control.Label
        TrimDirectionSelectButtonGroup  matlab.ui.container.ButtonGroup
        zButton_2                       matlab.ui.control.RadioButton
        yButton_2                       matlab.ui.control.RadioButton
        xButton_2                       matlab.ui.control.RadioButton
        FixLegandHoofDirectionButton    matlab.ui.control.Button
        PCATrimmerAppButton             matlab.ui.control.Button
        filenameField                   matlab.ui.control.EditField
        ImportButton                    matlab.ui.control.Button
    end

    
    properties (Access = private)
        ptCloud % Original Data
        importedfig % 
        newPoint    %　位置合わせ中の点群データ変数
        fixedPoint % 位置合わせ完了した時の点群データ
        secondFig   %　
        cutWidth      %　切る幅
        cutAxes
        convexMatrix % 回転行列
        trimAxes
        trimerrangeUp
        tirmerrangeDown
        
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            updatedata(app, app.convexMatrix);
            app.cutWidth = app.cutWidthTEditField.Value;
            app.trimAxes = app.TrimDirectionSelectButtonGroup.SelectedObject.Text;
            app.cutAxes = app.AxesSelectButtonGroup.SelectedObject.Text;
        end

        % Button pushed function: ImportButton
        function ImportButtonPushed(app, event)
            filename = uigetfile(["*.stl", "*.ply"]);
            DoShow = true;

            app.ptCloud = import3Dmodel(filename, DoShow);
            app.filenameField.Value = filename;

             % インポート状態での主成分分析、軸描写
            [app.importedfig, coeff, app.newPoint] = drawPCaxis3D(app.ptCloud.Location);
            app.convexMatrix = coeff;
            % 新点群での主成分分析、軸修正、描写
            app.secondFig = scorePlot(app.newPoint);
            hold off;
            % 初期状態でreferPoint, referConvを設定
%             app.referPoint = app.newPoint;
%             app.referConv = app.convexMatrix;
        end

        % Button pushed function: PCATrimmerAppButton
        function PCATrimmerAppButtonPushed(app, event)
            Point = app.newPoint;
            switch app.trimAxes 
                case 'x'
                    Point = Point(Point(:,1) > trimerangeDown & trimerangeUp > Point(:,1), :);
                case 'y'
                    Point = Point(Point(:,2) > trimerangeDown & trimerangeUp > Point(:,2), :);
                case 'z'
                    Point = Point(Point(:,3) > trimerangeDown & trimerangeUp > Point(:,3), :);
            end
            [app.trimedfig, coeff, app.fixedPoint] = drawPCaxis3D(Point); 
            app.trimedfig = scorePlot(app.fixedPoint);
            app.newPoint = app.fixedPoint;
            app.convMatrix = app.convMatrix * coeff; 
        end

        % Value changed function: TrimerangeUp
        function TrimerangeUpValueChanged(app, event)
            value = app.TrimerangeUp.Value;
            app.trimerangeUp = value;
        end

        % Value changed function: TrimerangeDown
        function TrimerangeDownValueChanged(app, event)
            value = app.TrimerangeDown.Value;
            app.trimerangeDown = value;
        end

        % Selection changed function: TrimDirectionSelectButtonGroup
        function TrimDirectionSelectButtonGroupSelectionChanged(app, event)
            selectedButton = app.TrimDirectionSelectButtonGroup.SelectedObject;
            app.trimAxes = selectedButton.Text;
        end

        % Button pushed function: FixLegandHoofDirectionButton
        function FixLegandHoofDirectionButtonPushed(app, event)
            tipWidth = 40;
            sPoint= fixLegsDirection(app.newPoint, app.trimAxes, app.cutWidth);
            app.fixedPoint = fixHoofTipDirection(sPoint, app.trimAxes, tipWidth, 2);
            pcshowWithAxes(app.fixedPoint);
        end

        % Value changed function: cutWidthTEditField
        function cutWidthTEditFieldValueChanged(app, event)
            value = app.cutWidthTEditField.Value;
            app.cutWidth = value;
        end

        % Selection changed function: AxesSelectButtonGroup
        function AxesSelectButtonGroupSelectionChanged(app, event)
            selectedButton = app.AxesSelectButtonGroup.SelectedObject;
            app.cutAxes = selectedButton.Text;
        end

        % Button pushed function: CalculateCircumButton
        function CalculateCircumButtonPushed(app, event)
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.ImportButton = uibutton(app.UIFigure, 'push');
            app.ImportButton.ButtonPushedFcn = createCallbackFcn(app, @ImportButtonPushed, true);
            app.ImportButton.Text = 'Import ';
            app.filenameField = uieditfield(app.UIFigure, 'text');
            app.PostureControlPanel = uipanel(app.UIFigure);
            app.PostureControlPanel.Title = 'Posture Control';
            app.PCATrimmerAppButton = uibutton(app.PostureControlPanel, 'push');
            app.PCATrimmerAppButton.ButtonPushedFcn = createCallbackFcn(app, @PCATrimmerAppButtonPushed, true);
            app.PCATrimmerAppButton.Text = 'Trimming';
            app.FixLegandHoofDirectionButton = uibutton(app.PostureControlPanel, 'push');
            app.FixLegandHoofDirectionButton.ButtonPushedFcn = createCallbackFcn(app, @FixLegandHoofDirectionButtonPushed, true);
            app.FixLegandHoofDirectionButton.Text = {'Fix Leg and Hoof'; ' Direction'};
            app.TrimDirectionSelectButtonGroup = uibuttongroup(app.PostureControlPanel);
            app.TrimDirectionSelectButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @TrimDirectionSelectButtonGroupSelectionChanged, true);
            app.TrimDirectionSelectButtonGroup.Title = 'Axes Select';
            app.xButton_2 = uiradiobutton(app.TrimDirectionSelectButtonGroup);
            app.xButton_2.Text = 'x';
            app.xButton_2.Value = true;
            app.yButton_2 = uiradiobutton(app.TrimDirectionSelectButtonGroup);
            app.yButton_2.Text = 'y';
            app.zButton_2 = uiradiobutton(app.TrimDirectionSelectButtonGroup);
            app.zButton_2.Text = 'z';
            app.Label = uilabel(app.PostureControlPanel);
            app.Label.Text = '上端';
            app.TrimerangeUp = uieditfield(app.PostureControlPanel, 'numeric');
            app.TrimerangeUp.ValueChangedFcn = createCallbackFcn(app, @TrimerangeUpValueChanged, true);
            app.Label_2 = uilabel(app.PostureControlPanel);
            app.Label_2.Text = '下端';
            app.TrimerangeDown = uieditfield(app.PostureControlPanel, 'numeric');
            app.TrimerangeDown.ValueChangedFcn = createCallbackFcn(app, @TrimerangeDownValueChanged, true);
            app.ResetPostureButton = uibutton(app.PostureControlPanel, 'push');
            app.ResetPostureButton.Text = 'Reset';
            app.OKButton = uibutton(app.PostureControlPanel, 'push');
            app.OKButton.Text = 'OK';
            app.CalculateAutoPanel = uipanel(app.UIFigure);
            app.CalculateAutoPanel.Title = 'Calculate Auto';
            app.CalculateCircumButton = uibutton(app.CalculateAutoPanel, 'push');
            app.CalculateCircumButton.ButtonPushedFcn = createCallbackFcn(app, @CalculateCircumButtonPushed, true);
            app.CalculateCircumButton.Text = 'Calculate Circum';
            app.cutWidthTEditFieldLabel = uilabel(app.CalculateAutoPanel);
            app.cutWidthTEditFieldLabel.Text = 'cutWidth T';
            app.cutWidthTEditField = uieditfield(app.CalculateAutoPanel, 'numeric');
            app.cutWidthTEditField.ValueChangedFcn = createCallbackFcn(app, @cutWidthTEditFieldValueChanged, true);
            app.cutWidthTEditField.Value = 0.5;
            app.AxesSelectButtonGroup = uibuttongroup(app.CalculateAutoPanel);
            app.AxesSelectButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @AxesSelectButtonGroupSelectionChanged, true);
            app.AxesSelectButtonGroup.Title = 'Axes Select';
            app.xButton = uiradiobutton(app.AxesSelectButtonGroup);
            app.xButton.Text = 'x';
            app.xButton.Value = true;
            app.yButton = uiradiobutton(app.AxesSelectButtonGroup);
            app.yButton.Text = 'y';
            app.zButton = uiradiobutton(app.AxesSelectButtonGroup);
            app.zButton.Text = 'z';
            app.ResetCalAutoButton = uibutton(app.CalculateAutoPanel, 'push');
            app.ResetCalAutoButton.Text = 'Reset';
            app.ResetAllButton = uibutton(app.UIFigure, 'push');
            app.ResetAllButton.Text = 'Reset';
            app.SelectManualyPanel = uipanel(app.UIFigure);
            app.SelectManualyPanel.Title = 'Select Manualy';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = main

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end